classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button_3   matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        Button     matlab.ui.control.Button
        ImageAxes  matlab.ui.control.UIAxes
        LossAxes   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        importedImage = []; % Store the imported image % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 1. 加载特征和标签
            load('resnet50_features.mat');

            % 2. 设置训练参数
            numEpochs = 1;
            learningRate = 0.01;
            numTriplets = 500;
            tripletMargin = 0.2;
            lambda = 0.00001;  % L2 regularization weight

            % 3. 生成triplet样本
            tripletIndices = generateTriplets(features, outputLabels, numTriplets);

            % 4. 初始化度量学习模型
            embeddingSize = 128;
            embeddingLayer = fullyConnectedLayer(embeddingSize, 'Name', 'embeddingLayer');
            embeddingLayer.Weights = randn(embeddingSize, size(features, 1)) * 0.01;
            embeddingLayer.Bias = zeros(embeddingSize, 1);
            layers = [imageInputLayer([size(features, 1), 1], 'Normalization', 'none', 'Name', 'input'); embeddingLayer];
            metricModel = dlnetwork(layerGraph(layers));

            % 5. 训练度量学习模型
            lossValues = [];  % 初始化一个空数组来存储损失值
            figure;  % 创建一个新的图像窗口

            for epoch = 1:numEpochs
                fprintf('Epoch %d/%d\n', epoch, numEpochs);

                for i = 1:numTriplets
                    % 获取锚点、正样本和负样本的特征
                    anchor = features(:, tripletIndices(1, i));
                    positive = features(:, tripletIndices(2, i));
                    negative = features(:, tripletIndices(3, i));

                    % 调整特征矩阵的形状以适应predict函数
                    anchor = reshape(anchor, [1, size(anchor, 1), 1]);
                    positive = reshape(positive, [1, size(positive, 1), 1]);
                    negative = reshape(negative, [1, size(negative, 1), 1]);

                    % 更新度量学习模型的参数
                    [loss, gradients] = dlfeval(@modelGradients, metricModel, anchor, positive, negative, tripletMargin, lambda);
                    metricModel.Learnables = updateLearnables(metricModel.Learnables, gradients, learningRate);

                    % 将损失值添加到数组
                    lossValues(end + 1) = loss;

                    % 显示每个triplet的损失值
                    if mod(i, 100) == 0
                        fprintf('Triplet %d/%d: Loss = %.4f\n', i, numTriplets, loss);
                    end

                    % 更新损失值图像
                    plot(lossValues);
                    xlabel('Iteration');
                    ylabel('Loss');
                    title(['Epoch ', num2str(epoch), '/', num2str(numEpochs)]);
                    drawnow;  % 强制更新图像
                end
            end

            % 6. 保存训练好的度量学习模型和嵌入大小
            save('metric_model.mat', 'metricModel', 'embeddingSize');

            % 7. 准备度量学习模型的输出作为ELM的输入
            numInstances = size(features, 2);
            embeddedFeatures = zeros(embeddingSize, numInstances);

            for i = 1:numInstances
                inputFeature = reshape(features(:, i), [1, size(features, 1), 1]);
                embeddedFeatures(:, i) = extractdata(forward(metricModel, dlarray(inputFeature, 'SSC')));
            end

            % 8. 划分数据集
            [trainData, testData, trainLabels, testLabels] = splitData(embeddedFeatures, outputLabels);

            % 9. 设置ELM参数
            numHiddenNodes = 5000;
            activationFunction = 'sigmoidActivation';
            C = 1e-3;

            % 10. 训练ELM
            [hiddenWeights, outputWeights] = trainELM(trainData, trainLabels, numHiddenNodes, activationFunction, C);

            % 11. 测试ELM
            accuracy = testELM(testData, testLabels, hiddenWeights, outputWeights, activationFunction);

            % 12. 显示准确率
            fprintf('ELM Classification Accuracy: %.2f%%\n', accuracy * 100);

            % 13. 保存ELM的隐藏层权重和输出权重
            save('elm_weights.mat', 'hiddenWeights', 'outputWeights');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.pgm', 'Image Files (*.jpg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);
            app.importedImage = imread(fullPath);
            imshow(app.importedImage, 'Parent', app.ImageAxes);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if isempty(app.importedImage)
                msgbox('请先导入一张图像', '错误', 'error');
                return;
            end

            % 1. 预处理图像
            inputImage = app.importedImage;
            targetSize = [224, 224]; % 网络输入层的大小
            numChannels = 3; % 颜色通道数
            inputImage = imresize(inputImage, targetSize);

            % 确保图像具有正确的颜色通道数量
            if size(inputImage, 3) ~= numChannels
                inputImage = repmat(inputImage, 1, 1, numChannels);
            end

            % 2. 使用预训练的ResNet50网络进行特征提取
            net = resnet50;
            featureLayer = 'avg_pool';
            features = activations(net, inputImage, featureLayer, 'OutputAs', 'columns');

            % 3. 加载训练好的度量学习模型
            load('metric_model.mat');

            % 4. 使用度量学习模型计算嵌入特征
            embeddingSize = 128;
            inputFeature = reshape(features, [1, size(features, 1), 1]);
            embeddedFeatures = extractdata(forward(metricModel, dlarray(inputFeature, 'SSC')));

            % 5. 使用ELM进行分类
            % 加载ELM的隐藏层权重和输出权重
            load('elm_weights.mat');

            % 设置ELM参数
            activationFunction = 'sigmoidActivation';  

            % 计算输出
            categoryLabels = ["s1", "s10", "s11", "s12", "s13", "s14", "s15", "s16", "s17", "s18", "s19", "s2", "s20", "s21", "s22", "s23", "s24", "s25", "s26", "s27", "s28", "s29", "s3", "s30", "s31", "s32", "s33", "s34", "s35", "s36", "s37", "s38", "s39", "s4", "s40", "s5", "s6", "s7", "s8", "s9"];
            [~, predictedLabelIndices] = testELM(embeddedFeatures, [], hiddenWeights, outputWeights, activationFunction);
            predictedLabels = categoryLabels(predictedLabelIndices);
            result = sprintf('检测结果: %s', predictedLabels);
            msgbox(result, '检测完成');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LossAxes = uiaxes(app.UIFigure);
            title(app.LossAxes, '训练LOSS值图像')
            xlabel(app.LossAxes, '轮数')
            ylabel(app.LossAxes, 'LOSS值')
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, '待检测图像')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '训练';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '导入图像';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '检测';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end